classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResolverButton            matlab.ui.control.Button
        SementeEditField          matlab.ui.control.NumericEditField
        SementeEditFieldLabel     matlab.ui.control.Label
        GeracoesEditField         matlab.ui.control.NumericEditField
        GeraesEditFieldLabel      matlab.ui.control.Label
        PopulacaoEditField        matlab.ui.control.NumericEditField
        PopulaoEditFieldLabel     matlab.ui.control.Label
        MtodoDropDown             matlab.ui.control.DropDown
        MtodoDropDownLabel        matlab.ui.control.Label
        CargaEditField            matlab.ui.control.NumericEditField
        CargaTotalEditFieldLabel  matlab.ui.control.Label
        DespachoEconmicoLabel     matlab.ui.control.Label
        ResultadosTable           matlab.ui.control.Table
        InputTable                matlab.ui.control.Table
        ConvergenciaUIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        hist 
    end
    
    methods (Access = private)
        
        function [state, options, optchanged] = registarGeracao(app, options, state, flag)

            optchanged = false;
        
            if strcmp(flag, 'init')
                app.hist = [];
            end
        
            if ~isempty(state.Score)
                app.hist(end+1) = min(state.Score);
            end
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InputTable.Data = {
                'G1', 0.24, 0.43;
                'G2', 0.37, 0.24
                };

            app.hist = [];
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)

            %% Ler dados da tabela
        
            dados = app.InputTable.Data;
        
            try
                b = cell2mat(dados(:,2))';
                c = cell2mat(dados(:,3))';
            catch
                uialert(app.UIFigure, ...
                    'Os valores de b e c devem ser numéricos.', ...
                    'Dados inválidos');
                return
            end
        
        
            %% Ler carga
        
            D = app.CargaEditField.Value;
        
        
            %% Limites dos geradores
        
            Pmin = [0.10 0.10];
            Pmax = [1.00 1.00];
        
        
            %% Validação
        
            if any(~isfinite(b)) || any(~isfinite(c)) || any(c <= 0)
                uialert(app.UIFigure, ...
                    'Os coeficientes b e c devem ser válidos e c deve ser positivo.', ...
                    'Dados inválidos');
                return
            end
        
            if ~isfinite(D) || D <= 0 || D < sum(Pmin) || D > sum(Pmax)
                uialert(app.UIFigure, ...
                    'A carga não é válida ou não pode ser satisfeita pelos geradores.', ...
                    'Dados inválidos');
                return
            end
        
        
            %% Método escolhido
        
            metodo = app.MtodoDropDown.Value;
            cla(app.ConvergenciaUIAxes);
        
            switch metodo
        
                case 'Lagrange'
        
                    resultado = Lagrange(b,c,D,Pmin,Pmax,[]);
        
                    P = resultado.P;
                    C = resultado.C;
                    lambda = resultado.lambda;
        
                    app.ResultadosTable.Data = {

                        'P1 (MW)', P(1);
                        'P2 (MW)', P(2);
                    
                        'Custo Total', C;
                    
                        'Lambda Sistema', resultado.lambdaSystem;
                    
                        'Lambda G1', lambda(1);
                        'Lambda G2', lambda(2);
                    
                        'Diferença Lambdas', resultado.lambdaDif
                    
                    };


                case 'GA'
                
                    app.hist = [];
                
                    outputFcn = @(options,state,flag) ...
                        registarGeracao(app,options,state,flag);
                
                    resultado = GA( ...
                        b, c, D, ...
                        app.PopulacaoEditField.Value, ...
                        app.GeracoesEditField.Value, ...
                        app.SementeEditField.Value, ...
                        Pmin, Pmax, [], outputFcn);
                
                    P = resultado.P;
                    C = resultado.C;
                    lambda = resultado.lambda;
                
                    app.ResultadosTable.Data = {
                    
                        'P1 (MW)', P(1);
                        'P2 (MW)', P(2);
                    
                        'Custo Total', C;
                    
                        'Lambda Sistema', resultado.lambdaSystem;
                    
                        'Lambda G1', lambda(1);
                        'Lambda G2', lambda(2);
                    
                        'Melhor Custo', resultado.melhor;
                    
                        'Custo Médio', resultado.media;
                    
                        'Pior Custo', resultado.pior;
                    
                        'Desvio Padrão', resultado.desvioPadrao
                    
                    };
                
                    plot( ...
                        app.ConvergenciaUIAxes,...
                        app.hist,...
                        '-o',...
                        'LineWidth',1.5,...
                        'MarkerSize',4); 

                    xlim(app.ConvergenciaUIAxes,...
                        [1 length(app.hist)]);

                    ylim(app.ConvergenciaUIAxes,...
                        [min(app.hist)*0.999 ...
                        max(app.hist)*1.001]);
                
                    grid(app.ConvergenciaUIAxes, 'on');


                case 'Algoritmo Próprio'
                
                    resultado = GAProprio( ...
                        b,...
                        c,...
                        D,...
                        app.PopulacaoEditField.Value,...
                        app.GeracoesEditField.Value,...
                        app.SementeEditField.Value,...
                        Pmin,...
                        Pmax);
                
                    P = resultado.P;
                    C = resultado.C;
                    lambda = resultado.lambda;
                
                    app.ResultadosTable.Data = {
                
                        'P1 (MW)', P(1);
                        'P2 (MW)', P(2);
                        'Custo Total', C;
                
                        'Lambda Sistema', ...
                            resultado.lambdaSystem;
                
                        'Lambda G1', lambda(1);
                        'Lambda G2', lambda(2)
                
                    };
                
                    app.hist = resultado.hist;
                
                    plot(app.ConvergenciaUIAxes,...
                        1:length(app.hist),...
                        app.hist,...
                        '-o',...
                        'LineWidth',1.5);
                
                    grid(app.ConvergenciaUIAxes,'on');


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvergenciaUIAxes = uiaxes(app.UIFigure);
            title(app.ConvergenciaUIAxes, 'Convergência')
            xlabel(app.ConvergenciaUIAxes, 'Geração')
            ylabel(app.ConvergenciaUIAxes, 'Melhor custo')
            zlabel(app.ConvergenciaUIAxes, 'Z')
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = {'Gerador'; 'b'; 'c'};
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = [false true true];
            app.ResultadosTable = uitable(app.UIFigure);
            app.ResultadosTable.ColumnName = {'Resultados'; 'Valor'};
            app.ResultadosTable.RowName = {};
            app.DespachoEconmicoLabel = uilabel(app.UIFigure);
            app.DespachoEconmicoLabel.Text = 'Despacho Econômico';
            app.CargaTotalEditFieldLabel = uilabel(app.UIFigure);
            app.CargaTotalEditFieldLabel.Text = 'Carga Total:';
            app.CargaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CargaEditField.Value = 1.19;
            app.MtodoDropDownLabel = uilabel(app.UIFigure);
            app.MtodoDropDownLabel.Text = 'Método:';
            app.MtodoDropDown = uidropdown(app.UIFigure);
            app.MtodoDropDown.Items = {'Lagrange', 'GA', 'Algoritmo Próprio'};
            app.MtodoDropDown.Value = 'Lagrange';
            app.PopulaoEditFieldLabel = uilabel(app.UIFigure);
            app.PopulaoEditFieldLabel.Text = 'População:';
            app.PopulacaoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulacaoEditField.Value = 50;
            app.GeraesEditFieldLabel = uilabel(app.UIFigure);
            app.GeraesEditFieldLabel.Text = 'Gerações:';
            app.GeracoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeracoesEditField.Value = 100;
            app.SementeEditFieldLabel = uilabel(app.UIFigure);
            app.SementeEditFieldLabel.Text = 'Semente:';
            app.SementeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SementeEditField.Value = 1;
            app.ResolverButton = uibutton(app.UIFigure, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end